classdef PhasePlaneApp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                     matlab.ui.Figure
        GridLayout                   matlab.ui.container.GridLayout
        LeftPanel                    matlab.ui.container.Panel
        GridLayout2                  matlab.ui.container.GridLayout
        basin_grid_sizeEditField     matlab.ui.control.NumericEditField
        basingridsizeEditFieldLabel  matlab.ui.control.Label
        ymaxEditField                matlab.ui.control.NumericEditField
        ymaxEditFieldLabel           matlab.ui.control.Label
        xmaxEditField                matlab.ui.control.NumericEditField
        xmaxEditFieldLabel           matlab.ui.control.Label
        K_bEditField                 matlab.ui.control.NumericEditField
        K_bEditFieldLabel            matlab.ui.control.Label
        W_cEditField                 matlab.ui.control.NumericEditField
        W_cEditFieldLabel            matlab.ui.control.Label
        a_fEditField                 matlab.ui.control.NumericEditField
        a_fEditFieldLabel            matlab.ui.control.Label
        KEditField                   matlab.ui.control.NumericEditField
        KEditFieldLabel              matlab.ui.control.Label
        phiEditField                 matlab.ui.control.NumericEditField
        phiEditFieldLabel            matlab.ui.control.Label
        dEditField                   matlab.ui.control.NumericEditField
        dEditFieldLabel              matlab.ui.control.Label
        cEditField                   matlab.ui.control.NumericEditField
        cEditFieldLabel              matlab.ui.control.Label
        bEditField                   matlab.ui.control.NumericEditField
        bEditFieldLabel              matlab.ui.control.Label
        aEditField                   matlab.ui.control.NumericEditField
        aEditFieldLabel              matlab.ui.control.Label
        UpdateButton                 matlab.ui.control.Button
        RightPanel                   matlab.ui.container.Panel
        Hyperlink_2                  matlab.ui.control.Hyperlink
        ModelMPhasePlaneLabel        matlab.ui.control.Label
        UIAxes                       matlab.ui.control.UIAxes
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            p.a   = app.aEditField.Value;
            p.b   = app.bEditField.Value;
            p.c   = app.cEditField.Value;
            p.d   = app.dEditField.Value;
            p.phi = app.phiEditField.Value;
            p.K   = app.KEditField.Value;
            p.a_f = app.a_fEditField.Value;
            p.W_c = app.W_cEditField.Value;
            p.K_b = app.K_bEditField.Value;

            p.basin_grid_size = app.basin_grid_sizeEditField.Value;
            
            xmax = app.xmaxEditField.Value;
            ymax = app.ymaxEditField.Value;

            drawBasins(app.UIAxes, p, xmax, ymax)
            drawPhasePlane(app.UIAxes, p, xmax, ymax)
        end

        % Button pushed function: UpdateButton
        function UpdateButtonPushed(app, event)
            p.a   = app.aEditField.Value;
            p.b   = app.bEditField.Value;
            p.c   = app.cEditField.Value;
            p.d   = app.dEditField.Value;
            p.phi = app.phiEditField.Value;
            p.K   = app.KEditField.Value;
            p.a_f = app.a_fEditField.Value;
            p.W_c = app.W_cEditField.Value;
            p.K_b = app.K_bEditField.Value;

            p.basin_grid_size = app.basin_grid_sizeEditField.Value;
            
            xmax = app.xmaxEditField.Value;
            ymax = app.ymaxEditField.Value;
            
            drawBasins(app.UIAxes, p, xmax, ymax)
            drawPhasePlane(app.UIAxes, p, xmax, ymax)
        end

        % Button down function: UIAxes
        function UIAxesButtonDown(app, event)
            point = event.IntersectionPoint;

            C0 = point(1);
            M0 = point(2);
        
            % Ignore clicks outside the phase plane
            if C0 < app.UIAxes.XLim(1) || C0 > app.UIAxes.XLim(2) || ...
               M0 < app.UIAxes.YLim(1) || M0 > app.UIAxes.YLim(2)
                return
            end

            p.a   = app.aEditField.Value;
            p.b   = app.bEditField.Value;
            p.c   = app.cEditField.Value;
            p.d   = app.dEditField.Value;
            p.phi = app.phiEditField.Value;
            p.K   = app.KEditField.Value;
            p.a_f = app.a_fEditField.Value;
            p.W_c = app.W_cEditField.Value;
            p.K_b = app.K_bEditField.Value;

            drawTrajectory(app.UIAxes, p, C0, M0)
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.UIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {823, 823};
                app.GridLayout.ColumnWidth = {'1x'};
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {249, '1x'};
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 2;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.GridLayout2 = uigridlayout(app.LeftPanel);
            app.UpdateButton = uibutton(app.GridLayout2, 'push');
            app.UpdateButton.ButtonPushedFcn = createCallbackFcn(app, @UpdateButtonPushed, true);
            app.UpdateButton.Text = 'Update';
            app.aEditFieldLabel = uilabel(app.GridLayout2);
            app.aEditFieldLabel.Text = 'a   =';
            app.aEditField = uieditfield(app.GridLayout2, 'numeric');
            app.aEditField.Value = 5;
            app.bEditFieldLabel = uilabel(app.GridLayout2);
            app.bEditFieldLabel.Text = 'b   =';
            app.bEditField = uieditfield(app.GridLayout2, 'numeric');
            app.bEditField.Value = 2;
            app.cEditFieldLabel = uilabel(app.GridLayout2);
            app.cEditFieldLabel.Text = 'c   =';
            app.cEditField = uieditfield(app.GridLayout2, 'numeric');
            app.cEditField.Value = 0.5;
            app.dEditFieldLabel = uilabel(app.GridLayout2);
            app.dEditFieldLabel.Text = 'd   =';
            app.dEditField = uieditfield(app.GridLayout2, 'numeric');
            app.dEditField.Value = 1;
            app.phiEditFieldLabel = uilabel(app.GridLayout2);
            app.phiEditFieldLabel.Text = 'phi   =';
            app.phiEditField = uieditfield(app.GridLayout2, 'numeric');
            app.phiEditField.Limits = [0 1];
            app.phiEditField.Value = 1;
            app.KEditFieldLabel = uilabel(app.GridLayout2);
            app.KEditFieldLabel.Text = 'K   =';
            app.KEditField = uieditfield(app.GridLayout2, 'numeric');
            app.KEditField.Value = 10;
            app.a_fEditFieldLabel = uilabel(app.GridLayout2);
            app.a_fEditFieldLabel.Text = 'a_f   =';
            app.a_fEditField = uieditfield(app.GridLayout2, 'numeric');
            app.a_fEditField.Value = 1;
            app.W_cEditFieldLabel = uilabel(app.GridLayout2);
            app.W_cEditFieldLabel.Text = 'W_c   =';
            app.W_cEditField = uieditfield(app.GridLayout2, 'numeric');
            app.W_cEditField.Value = 1;
            app.K_bEditFieldLabel = uilabel(app.GridLayout2);
            app.K_bEditFieldLabel.Text = 'K_b   =';
            app.K_bEditField = uieditfield(app.GridLayout2, 'numeric');
            app.K_bEditField.Value = 1;
            app.xmaxEditFieldLabel = uilabel(app.GridLayout2);
            app.xmaxEditFieldLabel.Text = 'x max';
            app.xmaxEditField = uieditfield(app.GridLayout2, 'numeric');
            app.xmaxEditField.Value = 6;
            app.ymaxEditFieldLabel = uilabel(app.GridLayout2);
            app.ymaxEditFieldLabel.Text = 'y max';
            app.ymaxEditField = uieditfield(app.GridLayout2, 'numeric');
            app.ymaxEditField.Value = 6;
            app.basingridsizeEditFieldLabel = uilabel(app.GridLayout2);
            app.basingridsizeEditFieldLabel.Text = 'basin grid size';
            app.basin_grid_sizeEditField = uieditfield(app.GridLayout2, 'numeric');
            app.basin_grid_sizeEditField.Value = 30;
            app.RightPanel = uipanel(app.GridLayout);
            app.UIAxes = uiaxes(app.RightPanel);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes.ButtonDownFcn = createCallbackFcn(app, @UIAxesButtonDown, true);
            app.ModelMPhasePlaneLabel = uilabel(app.RightPanel);
            app.ModelMPhasePlaneLabel.Text = 'Model M Phase Plane';
            app.Hyperlink_2 = uihyperlink(app.RightPanel);
            app.Hyperlink_2.URL = 'https://github.com/louisd-w/model-m-phase-plane/';
            app.Hyperlink_2.Text = 'Github';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = PhasePlaneApp

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end